classdef MotionAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionAnalysisUIFigure         matlab.ui.Figure
        MotionAnalysisAppLabel         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        FeatureExtractionTab           matlab.ui.container.Tab
        RIGHTLEGButton                 matlab.ui.control.Button
        SPECTRAButton                  matlab.ui.control.Button
        CutoffSliderLabel              matlab.ui.control.Label
        CutoffSlider                   matlab.ui.control.Slider
        FILTERSIGNALButton             matlab.ui.control.Button
        FILTERSPECTRAButton            matlab.ui.control.Button
        GETCHARACTERISTICSButton       matlab.ui.control.Button
        GaitcharacteristicsLabel       matlab.ui.control.Label
        DistancemEditFieldLabel        matlab.ui.control.Label
        DistancemEditField             matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        SAVEFEATURESTOPATHButton       matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        LEFTLEGButton                  matlab.ui.control.Button
        FeatureExtractionLabel         matlab.ui.control.Label
        UploadrightlegrecordLabel      matlab.ui.control.Label
        UploadleftlegrecordLabel       matlab.ui.control.Label
        PatientsnameEditFieldLabel     matlab.ui.control.Label
        PatientsnameEditField          matlab.ui.control.EditField
        PresstosavefeaturesLabel       matlab.ui.control.Label
        ClassificationTab              matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        ClassifyButton                 matlab.ui.control.Button
        NumberofwindowsEditFieldLabel  matlab.ui.control.Label
        NumberofwindowsEditField       matlab.ui.control.NumericEditField
        CreatedatamatrixButton         matlab.ui.control.Button
        FrequencyrangeButtonGroup      matlab.ui.container.ButtonGroup
        HzButton                       matlab.ui.control.RadioButton
        HzButton_2                     matlab.ui.control.RadioButton
        HzButton_3                     matlab.ui.control.RadioButton
        UIAxes7                        matlab.ui.control.UIAxes
        ClassificationLabel            matlab.ui.control.Label
        NumberofneighboursButtonGroup  matlab.ui.container.ButtonGroup
        Button_3                       matlab.ui.control.RadioButton
        Button_4                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        LoaddatamatrixButton           matlab.ui.control.Button
        LoaddatamatrixorcreateanewoneLabel  matlab.ui.control.Label
    end


    properties (Access = public)
            file;file1;file2;
            orig_file1; orig_file2;
            opts;T1; T2; 
            Fs = 100;L1;L2;freq1;freq2;
            
            filt;
            t1; t2;
            X1_acc; Y1_acc; Z1_acc; X2_acc; Y2_acc; Z2_acc; 
            X1_gyr; Y1_gyr; Z1_gyr; X2_gyr; Y2_gyr; Z2_gyr;
            winds_X1_acc; winds_Y1_acc; winds_Z1_acc; winds_X2_acc; winds_Y2_acc; winds_Z2_acc;
            winds_X1_gyr; winds_Y1_gyr; winds_Z1_gyr; winds_X2_gyr; winds_Y2_gyr; winds_Z2_gyr;
            
            energy_X1_acc; energy_Y1_acc; energy_Z1_acc; energy_X2_acc; energy_Y2_acc; energy_Z2_acc;
            energy_X1_gyr; energy_Y1_gyr; energy_Z1_gyr; energy_X2_gyr; energy_Y2_gyr; energy_Z2_gyr;
            energy_R1_acc; energy_R2_acc;
            energy_R1_gyr; energy_R2_gyr;
            winds_R1_acc; winds_R2_acc;
            winds_R1_gyr; winds_R2_gyr;
            a=6.25; b=12.5;
            num_of_winds=12;
            data_matrix; labels; col;
            R1_acc; R2_acc;
            R1_gyr; R2_gyr;
            cutoff_value=0;
            R1_acc_filtered; R1_gyr_filtered; R2_acc_filtered; R2_gyr_filtered;
            R1_acc_spectrum; R1_gyr_spectrum; R2_acc_spectrum; R2_gyr_spectrum;
            dist;
            walk_freq1; avg_step_length1; avg_speed1; walk_time1;
            walk_freq2; avg_step_length2; avg_speed2; walk_time2;
            result; PatternsTargets;
            knn=3;
            Patterns; Targets;
            newName;
            class_file;
            
            classificationResults;

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SPECTRAButton.Enable='off';
            app.FILTERSIGNALButton.Enable='off';
            app.CutoffSlider.Enable='off';
            app.FILTERSPECTRAButton.Enable='off';
            app.GETCHARACTERISTICSButton.Enable='off';
            app.SAVEFEATURESTOPATHButton.Enable='off';
            app.LEFTLEGButton.Enable='off';
            app.ClassifyButton.Enable='off';
            app.col=[1 0 0; 0 0 1; 0 1 0; 1 0 1; 0 1 1];
            
        end

        % Button pushed function: RIGHTLEGButton
        function RIGHTLEGButtonPushed(app, event)
            
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            cla(app.UIAxes_4, 'reset');
            [filename, folder]=uigetfile('*.csv');
            app.file=fullfile(folder, filename);
            app.file1=app.file;
            if app.file1==0 
                return
            else
                app.LEFTLEGButton.Enable='on';
                 app.UploadrightlegrecordLabel.Text = 'Uploaded';
          
            end

            %initialisng variables in case user didn't use slider


        end

        % Button pushed function: SPECTRAButton
        function SPECTRAButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            cla(app.UIAxes_4, 'reset');

            
                 % Sampling frequency
            
            transformed_R1_acc=fft(app.R1_acc-mean(app.R1_acc));
            transformed_R1_gyr=fft(app.R1_gyr-mean(app.R1_gyr));
            transformed_R2_acc=fft(app.R2_acc-mean(app.R2_acc));
            transformed_R2_gyr=fft(app.R2_gyr-mean(app.R2_gyr));
            [h1,w] = freqz(app.filt,length(app.freq1));
            [h2,w] = freqz(app.filt,length(app.freq2));
          
            yyaxis(app.UIAxes, 'left')
            plot(app.UIAxes,app.freq1,abs(transformed_R1_acc(1:app.L1/2+1)));  
            yyaxis(app.UIAxes, 'right')
            plot(app.UIAxes, app.freq1, abs(h1), '-r');
            
            yyaxis(app.UIAxes_2, 'left')
            plot(app.UIAxes_2, app.freq1,abs(transformed_R1_gyr(1:app.L1/2+1)));
            yyaxis(app.UIAxes_2, 'right')
            plot(app.UIAxes_2,app.freq1, abs(h1) ,'-r'); 
            
            yyaxis(app.UIAxes_3, 'left')
            plot(app.UIAxes_3,app.freq2,abs(transformed_R2_acc(1:app.L2/2+1)));
            yyaxis(app.UIAxes_3, 'right')
            plot(app.UIAxes_3, app.freq2, abs(h2),'-r'); 
            
            yyaxis(app.UIAxes_4, 'left')
            plot(app.UIAxes_4,app.freq2,abs(transformed_R2_gyr(1:app.L2/2+1)));
            yyaxis(app.UIAxes_4, 'right')
            plot(app.UIAxes_4, app.freq2, abs(h2),'-r'); 
            
            
            title(app.UIAxes,'FFT ACCELEOMETER, RIGHT LEG','FontWeight','bold');
            title(app.UIAxes_2,'FFT GYROSCOPE, RIGHT LEG','FontWeight','bold');
            title(app.UIAxes_3,'FFT ACCELEOMETER, LEFT LEG','FontWeight','bold');
            title(app.UIAxes_4,'FFT GYROSCOPE, LEFT LEG','FontWeight','bold');
            axis(app.UIAxes, 'tight');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            axis(app.UIAxes_2, 'tight');
            app.UIAxes_2.XGrid = 'on'; app.UIAxes_2.YGrid = 'on'; 
            axis(app.UIAxes_3, 'tight'); 
            app.UIAxes_3.XGrid = 'on'; app.UIAxes_3.YGrid = 'on';
            axis(app.UIAxes_4, 'tight'); 
            app.UIAxes_4.XGrid = 'on'; app.UIAxes_4.YGrid = 'on';
            
            xlabel(app.UIAxes,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_2,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_3,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_4,'frequency [Hz]','FontSize',10);
        end

        % Button pushed function: FILTERSIGNALButton
        function FILTERSIGNALButtonPushed(app, event)
                    %Filtering
                    
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            cla(app.UIAxes_4, 'reset');
            

            plot(app.UIAxes, app.t1,app.R1_acc_filtered); app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_2, app.t1,app.R1_gyr_filtered); app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_3, app.t2,app.R2_acc_filtered); app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_4, app.t2,app.R2_gyr_filtered); app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
             
            title(app.UIAxes,'FILTERED SIGNAL, ACCELEOMETER, RIGHT','FontWeight','bold');
            title(app.UIAxes_2,'FILTERED SIGNAL, GYROSCOPE, RIGHT','FontWeight','bold');
            title(app.UIAxes_3,'FILTERED SIGNAL, ACCELEOMETER, LEFT','FontWeight','bold');
            title(app.UIAxes_4,'FILTERED SIGNAL, GYROSCOPE, LEFT','FontWeight','bold');
            axis(app.UIAxes, 'tight');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            axis(app.UIAxes_2, 'tight');
            app.UIAxes_2.XGrid = 'on'; app.UIAxes_2.YGrid = 'on'; 
            axis(app.UIAxes_3, 'tight'); 
            app.UIAxes_3.XGrid = 'on'; app.UIAxes_3.YGrid = 'on';
            axis(app.UIAxes_4, 'tight'); 
            app.UIAxes_4.XGrid = 'on'; app.UIAxes_4.YGrid = 'on';
            
            xlabel(app.UIAxes,'time [s]','FontSize',10);
            xlabel(app.UIAxes_2,'time [s]','FontSize',10);
            xlabel(app.UIAxes_3,'time [s]','FontSize',10);
            xlabel(app.UIAxes_4,'time [s]','FontSize',10);
           

        end

        % Value changed function: CutoffSlider
        function CutoffSliderValueChanged(app, event)
            
            app.cutoff_value = app.CutoffSlider.Value;
            %app.cutoff_value=app.cutoff_value*(app.Fs/2);
            if(app.cutoff_value==0)
                app.cutoff_value=0.001;
            end 
            if (app.cutoff_value>0.9)
                app.cutoff_value=0.9;
                
            end
            app.filt=designfilt('lowpassfir', 'PassbandFrequency',  app.cutoff_value, 'StopbandFrequency', app.cutoff_value+0.05, 'PassbandRipple', 1, 'StopbandAttenuation', 60);        
            app.R1_acc_filtered = filter(app.filt,app.R1_acc-mean(app.R1_acc));
            app.R1_gyr_filtered = filter(app.filt,app.R1_gyr-mean(app.R1_gyr));
            app.R2_acc_filtered = filter(app.filt,app.R2_acc-mean(app.R2_acc));
            app.R2_gyr_filtered = filter(app.filt,app.R2_gyr-mean(app.R2_gyr));
            
            SPECTRAButtonPushed(app, event);


        end

        % Button pushed function: FILTERSPECTRAButton
        function FILTERSPECTRAButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            cla(app.UIAxes_3, 'reset');
            cla(app.UIAxes_4, 'reset');
            
            app.R1_acc_spectrum=fft(app.R1_acc_filtered-mean(app.R1_acc_filtered));
            app.R1_gyr_spectrum=fft(app.R1_gyr_filtered-mean(app.R1_gyr_filtered));
            app.R2_acc_spectrum=fft(app.R2_acc_filtered-mean(app.R2_acc_filtered));
            app.R2_gyr_spectrum=fft(app.R2_gyr_filtered-mean(app.R2_gyr_filtered));
            
            plot(app.UIAxes, app.freq1,abs(app.R1_acc_spectrum(1:app.L1/2+1)));
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_2, app.freq1,abs(app.R1_gyr_spectrum(1:app.L1/2+1)));
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_3, app.freq2,abs(app.R2_acc_spectrum(1:app.L2/2+1)));
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            plot(app.UIAxes_4, app.freq2,abs(app.R2_gyr_spectrum(1:app.L2/2+1)));
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
            title(app.UIAxes,'FILTERED SPECTRUM,ACCELEOMETER, RIGHT','FontWeight','bold');
            title(app.UIAxes_2,'FILTERED SPECTRUM, GYROSCOPE, RIGHT','FontWeight','bold');
            title(app.UIAxes_3,'FILTERED SPECTRUM,ACCELEOMETER, LEFT','FontWeight','bold');
            title(app.UIAxes_4,'FILTERED SPECTRUM, GYROSCOPE, LEFT','FontWeight','bold');
            axis(app.UIAxes, 'tight');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            axis(app.UIAxes_2, 'tight');
            app.UIAxes_2.XGrid = 'on'; app.UIAxes_2.YGrid = 'on'; 
            axis(app.UIAxes_3, 'tight'); 
            app.UIAxes_3.XGrid = 'on'; app.UIAxes_3.YGrid = 'on';
            axis(app.UIAxes_4, 'tight'); 
            app.UIAxes_4.XGrid = 'on'; app.UIAxes_4.YGrid = 'on';
            
            xlabel(app.UIAxes,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_2,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_3,'frequency [Hz]','FontSize',10);
            xlabel(app.UIAxes_4,'frequency [Hz]','FontSize',10);
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            app.dist = app.DistancemEditField.Value;
            
        end

        % Button pushed function: GETCHARACTERISTICSButton
        function GETCHARACTERISTICSButtonPushed(app, event)
            
            if (app.dist~=0)
            app.walk_time1=max(app.t1);
            app.walk_time2=max(app.t2);
            app.avg_speed1=app.dist/app.walk_time1;
            app.avg_speed2=app.dist/app.walk_time2;
            low_freqs=5;
            ampl1=abs(app.R2_acc_spectrum(low_freqs:app.L2/2+1)); %z-osa
            ampl2=abs(app.R2_acc_spectrum(low_freqs:app.L2/2+1)); %z-osa
            idx1=find(ampl1==max(ampl1))+low_freqs;
            app.walk_freq1=app.freq1(idx1);
            idx2=find(ampl2==max(ampl2))+low_freqs;
            app.walk_freq2=app.freq2(idx2);
            avg_steps_number1=app.walk_freq1*app.walk_time1;
            avg_steps_number2=app.walk_freq2*app.walk_time2;
            app.avg_step_length1=app.dist/avg_steps_number1;
            app.avg_step_length2=app.dist/avg_steps_number2;
            
            fre1 = 'Frequency of walk (right): ';
            fre2 = app.walk_freq1;
            fre3 = 'Frequency of walk (left): ';
            fre4 = app.walk_freq2;
            
            length1 = 'Length of step (right): ';
            length2 = app.avg_step_length1;
            length3 = 'Length of step (left): ';
            length4 = app.avg_step_length2;
            
            steps1 = 'Average number of steps (right): ';
            steps2 = avg_steps_number1;
            steps3 = 'Average number of steps (left): ';
            steps4 = avg_steps_number2;
            
            speed1 = 'Average speed (right): ';
            speed2 = app.avg_speed1;
            speed3 = 'Average speed (left): ';
            speed4 = app.avg_speed2;
            
            time1 = 'Time of walk (right): ';
            time2 = app.walk_time1;
            time3 = 'Time of walk (left): ';
            time4 = app.walk_time2;
            
            app.result=[fre2, length2, steps2, speed2, fre4,length4, steps4, speed4];
           
            cellArrayText{1} = sprintf('%s%0.2f', time1, time2, ' s');
            cellArrayText{2} = sprintf('%s%0.2f', fre1, fre2, ' Hz');
            cellArrayText{3} = sprintf('%s%0.2f', length1, length2, ' m');
            cellArrayText{4} = sprintf('%s%0.2f', steps1, steps2);
            cellArrayText{5} = sprintf('%s%0.2f', speed1, speed2, ' m/s');
            
            cellArrayText{6} = sprintf('%s%0.2f', time3, time4, ' s');
            cellArrayText{7} = sprintf('%s%0.2f', fre3, fre4, ' Hz');
            cellArrayText{8} = sprintf('%s%0.2f', length3, length4, ' m');
            cellArrayText{9} = sprintf('%s%0.2f', steps3, steps4);
            cellArrayText{10} = sprintf('%s%0.2f', speed3, speed4, ' m/s');
            
            app.GaitcharacteristicsLabel.Text = cellArrayText;
            app.SAVEFEATURESTOPATHButton.Enable='on';
            else
                
            app.GaitcharacteristicsLabel.Text = 'Please enter distance to see gait characteristics';
            end
        end

        % Callback function
        function TextAreaValueChanged(app, event)
       
        end

        % Button pushed function: SAVEFEATURESTOPATHButton
        function SAVEFEATURESTOPATHButtonPushed(app, event)
            if (app.newName~=0)
            result=app.result;
            name=strcat(app.newName, '_gait_features');
            save (name, 'result');
             app.PresstosavefeaturesLabel.Text = "Successfully saved";
            else
                app.PresstosavefeaturesLabel.Text = "Please enter name first";
            end 


        end

        % Button pushed function: LEFTLEGButton
        function LEFTLEGButtonPushed(app, event)
            [filename, folder]=uigetfile('*.csv');
            app.file=fullfile(folder, filename);
            app.file2=app.file;
            if app.file2==0 
                return
            else
                 app.UploadleftlegrecordLabel.Text = 'Uploaded';
            app.orig_file1=app.file1; %copy initial data
            app.orig_file2=app.file2; %copy initial data
                app.SPECTRAButton.Enable='on';
                app.FILTERSIGNALButton.Enable='on';
                app.CutoffSlider.Enable='on';
                app.FILTERSPECTRAButton.Enable='on';
                app.GETCHARACTERISTICSButton.Enable='on';
                
                 app.T1=preprocess(app.file1);
                 app.T2=preprocess(app.file2);
                 
                 app.L1 = length(app.T1(:,1));  %length of signal 1
            app.L2 = length(app.T2(:,1));  %length of signal 1
            app.freq1 = app.Fs/2*linspace(0,1,app.L1/2+1); %frekv prava
            app.freq2 = app.Fs/2*linspace(0,1,app.L2/2+1); %frekv leva
            
            %%%%%%%%%%%PREVZORKOVANI%%%%%%%%%%%%%%%%%%
            %accelerometer, prava
            [app.X1_acc,app.t1]=resample(app.T1(:,2), app.T1(:,1), app.Fs); %X, Y, Z
            app.Y1_acc=resample(app.T1(:,3), app.T1(:,1), app.Fs);
            app.Z1_acc=resample(app.T1(:,4), app.T1(:,1), app.Fs);
            
             %accelerometer, leva
            [app.X2_acc,app.t2]=resample(app.T2(:,2), app.T2(:,1), app.Fs); %X, Y, Z
            app.Y2_acc=resample(app.T2(:,3), app.T2(:,1), app.Fs);
            app.Z2_acc=resample(app.T2(:,4), app.T2(:,1), app.Fs);
            
            
            %gyroscope, prava
            [app.X1_gyr,app.t1]=resample(app.T1(:,5), app.T1(:,1), app.Fs); %X, Y, Z
            app.Y1_gyr=resample(app.T1(:,6), app.T1(:,1), app.Fs);
            app.Z1_gyr=resample(app.T1(:,7), app.T1(:,1), app.Fs);
           
            
            %gyroscope, leva
            [app.X2_gyr,app.t2]=resample(app.T2(:,5), app.T2(:,1), app.Fs); %X, Y, Z
            app.Y2_gyr=resample(app.T2(:,6), app.T2(:,1), app.Fs);
            app.Z2_gyr=resample(app.T2(:,7), app.T2(:,1), app.Fs);
            %%%%%%%%%% norm of signals in 3 directions %%%%%%%%%%%%%%%%%% NEW
            app.R1_acc=sqrt(app.X1_acc.^2+app.Y1_acc.^2+app.Z1_acc.^2); %prava acc
            app.R1_gyr=sqrt(app.X1_gyr.^2+app.Y1_gyr.^2+app.Z1_gyr.^2); %prava gyr
            app.R2_acc=sqrt(app.X2_acc.^2+app.Y2_acc.^2+app.Z2_acc.^2); %leva acc
            app.R2_gyr=sqrt(app.X2_gyr.^2+app.Y2_gyr.^2+app.Z2_gyr.^2); %leva gyr
                      
            
            app.filt=designfilt('lowpassfir', 'PassbandFrequency',  0.5, 'StopbandFrequency', 0.55, 'PassbandRipple', 1, 'StopbandAttenuation', 60);        
            app.R1_acc_filtered = filter(app.filt,app.R1_acc-mean(app.R1_acc));
            app.R1_gyr_filtered = filter(app.filt,app.R1_gyr-mean(app.R1_gyr));
            app.R2_acc_filtered = filter(app.filt,app.R2_acc-mean(app.R2_acc));
            app.R2_gyr_filtered = filter(app.filt,app.R2_gyr-mean(app.R2_gyr));
            app.R1_acc_spectrum=fft(app.R1_acc_filtered-mean(app.R1_acc_filtered));
            app.R1_gyr_spectrum=fft(app.R1_gyr_filtered-mean(app.R1_gyr_filtered));
            app.R2_acc_spectrum=fft(app.R2_acc_filtered-mean(app.R2_acc_filtered));
            app.R2_gyr_spectrum=fft(app.R2_gyr_filtered-mean(app.R2_gyr_filtered));
            
            plot(app.UIAxes,app.t1, app.R1_acc);
            plot(app.UIAxes_2,app.t1,  app.R1_gyr);
            plot(app.UIAxes_3,app.t2, app.R2_acc);
            plot(app.UIAxes_4,app.t2, app.R2_gyr);
            
            title(app.UIAxes,'Right leg, accelerometer','FontWeight','bold'); 
            title(app.UIAxes_2,'Right leg, gyroscope','FontWeight','bold');
            title(app.UIAxes_3,'Left leg, accelerometer','FontWeight','bold');
            title(app.UIAxes_4,'Left leg, gyroscope','FontWeight','bold');
            
            axis(app.UIAxes, 'tight');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; 
            axis(app.UIAxes_2, 'tight');
            app.UIAxes_2.XGrid = 'on'; app.UIAxes_2.YGrid = 'on'; 
            axis(app.UIAxes_3, 'tight'); 
            app.UIAxes_3.XGrid = 'on'; app.UIAxes_3.YGrid = 'on'; 
            axis(app.UIAxes_4, 'tight'); 
            app.UIAxes_4.XGrid = 'on'; app.UIAxes_4.YGrid = 'on'; 
            
            xlabel(app.UIAxes,'time [s]','FontSize',10);
            xlabel(app.UIAxes_2,'time [s]','FontSize',10);
            xlabel(app.UIAxes_3,'time [s]','FontSize',10);
            xlabel(app.UIAxes_4,'time [s]','FontSize',10);
            
           
            end
            
        end

        % Button pushed function: CreatedatamatrixButton
        function CreatedatamatrixButtonPushed(app, event)
            if ~isempty(app.R1_acc_spectrum)
            app.winds_R1_acc= return_parts(app.R1_acc,app.num_of_winds);
            app.energy_R1_acc=energiesV1(app.winds_R1_acc, app.Fs, app.a,app.b);
            app.winds_R1_gyr= return_parts(app.R1_gyr,app.num_of_winds);
            app.energy_R1_gyr=energiesV1(app.winds_R1_gyr, app.Fs, app.a,app.b);
            app.winds_R2_acc= return_parts(app.R2_acc,app.num_of_winds);
            app.energy_R2_acc=energiesV1(app.winds_R2_acc, app.Fs, app.a,app.b);
            app.winds_R2_gyr= return_parts(app.R2_gyr,app.num_of_winds);
            app.energy_R2_gyr=energiesV1(app.winds_R2_gyr, app.Fs, app.a,app.b);
            
            app.data_matrix=[[app.energy_R1_acc' app.energy_R1_gyr'];
                [app.energy_R2_acc' app.energy_R2_gyr']];
            app.labels=[ones(1, length(app.energy_R1_acc)) 2*ones(1, length(app.energy_R2_acc))];
            
            %%% alternativa pro grafiku
            
            %            set(4,'Units','Normal','Position',[0 0.5 0.3 0.4])
            %             gscatter(app.data_matrix(:,1),app.data_matrix(:,2),app.labels',app.col,'o*+',10); grid on; axis tight;
            %             title(['   CLASSIFICATION DATA',  '\newline', 'PATTERN AND TARGET VALUES']);
            %             xlabel('Normalized Feature 1'); ylabel('Normalized Feature 2');
            scatter(app.UIAxes5,app.data_matrix(:,1),  app.data_matrix(:,2), 50, app.col(app.labels, :), 'LineWidth',2); %,app.labels',app.col,'o*+',10);
            axis(app.UIAxes5, 'tight');
            app.UIAxes5.XGrid = 'on'; app.UIAxes5.YGrid = 'on';
            title(app.UIAxes5,['   CLASSIFICATION DATA, PATTERN AND TARGET VALUES'],'FontWeight','bold');
            xlabel(app.UIAxes5,'Normalized Feature 1','FontSize',10);
            ylabel(app.UIAxes5,'Normalized Feature 2','FontSize',10);
            %plot(app.UIAxes_6,app.t2, app.R2_acc);
            app.PatternsTargets=[app.data_matrix, app.labels'];
            PatternsTargets=app.PatternsTargets;
            name=strcat(app.newName, '_pattern_targets');
            save (name, 'PatternsTargets');
            app.ClassifyButton.Enable='on';
            
            else
                 app.LoaddatamatrixorcreateanewoneLabel.Text = 'Please load data in Feature Extraction tab';
            end
        end

        % Value changed function: NumberofwindowsEditField
        function NumberofwindowsEditFieldValueChanged(app, event)
            app.num_of_winds = app.NumberofwindowsEditField.Value;
            
            
        end

        % Selection changed function: FrequencyrangeButtonGroup
        function FrequencyrangeButtonGroupSelectionChanged(app, event)
            % selectedButton = app.FrequencyrangeButtonGroup.SelectedObject;
            if app.HzButton.Value==1
                app.a=6.25;
                app.b=12.5;
            elseif  app.HzButton_2.Value==1
                app.a=12.5;
                app.b=25;
            elseif  app.HzButton_3.Value==1
                app.a=25;
                app.b=50;
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if ~isempty(app.R1_acc_spectrum)
                name=strcat(app.newName, '_pattern_targets');
                data=load(name);
                data=data.PatternsTargets;
                app.Patterns=data(:,1:2);
                app.Targets=data(:,3);
                scatter(app.UIAxes5, data(:,1),  data(:,2), 50, app.col(app.Targets, :), 'LineWidth',2); %,app.labels',app.col,'o*+',10);
                axis(app.UIAxes5, 'tight');
                app.UIAxes5.XGrid = 'on'; app.UIAxes5.YGrid = 'on';
                title(app.UIAxes5,'   CLASSIFICATION DATA, PATTERN AND TARGET VALUES','FontWeight','bold');
                xlabel(app.UIAxes5,'Normalized Feature 1','FontSize',10);
                ylabel(app.UIAxes5,'Normalized Feature 2','FontSize',10);
            end
                
            
            %NumberofneighboursButtonGroupSelectionChanged(app, event);
            
            [ClassAccu2,CrossVal2]=C2knn(app.Patterns,app.Targets,app.col, app.knn, app);
            
            [ClassAccu5,CrossVal5]=C5nn(app.Patterns,app.Targets,app.col, app);
            
            app.classificationResults = [ClassAccu2,CrossVal2,ClassAccu5,CrossVal5];
            
        end

        % Selection changed function: NumberofneighboursButtonGroup
        function NumberofneighboursButtonGroupSelectionChanged(app, event)
            if app.Button_3.Value==1
                app.knn=3;
            elseif  app.Button_4.Value==1
                app.knn=5;
            elseif  app.Button_5.Value==1
                app.knn=7;
            end
            
        end

        % Value changed function: PatientsnameEditField
        function PatientsnameEditFieldValueChanged(app, event)
            name = app.PatientsnameEditField.Value;
            app.newName = strrep(name,' ','_');
        end

        % Button pushed function: LoaddatamatrixButton
        function LoaddatamatrixButtonPushed(app, event)
            app.class_file=uigetfile('*.mat');
            
            data=load(app.class_file);
            data=data.PatternsTargets;
            app.Patterns=data(:,1:2);
            app.Targets=data(:,3);
            scatter(app.UIAxes5, data(:,1),  data(:,2), 50, app.col(app.Targets, :), 'LineWidth',2); %,app.labels',app.col,'o*+',10);
            axis(app.UIAxes5, 'tight');
            app.UIAxes5.XGrid = 'on'; app.UIAxes5.YGrid = 'on';
            title(app.UIAxes5,'   CLASSIFICATION DATA, PATTERN AND TARGET VALUES','FontWeight','bold');
            xlabel(app.UIAxes5,'Normalized Feature 1','FontSize',10);
            ylabel(app.UIAxes5,'Normalized Feature 2','FontSize',10);
            app.ClassifyButton.Enable='on';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionAnalysisUIFigure = uifigure;
            app.MotionAnalysisUIFigure.Name = 'Motion Analysis';
            app.MotionAnalysisAppLabel = uilabel(app.MotionAnalysisUIFigure);
            app.MotionAnalysisAppLabel.Text = 'Motion Analysis App';
            app.TabGroup = uitabgroup(app.MotionAnalysisUIFigure);
            app.FeatureExtractionTab = uitab(app.TabGroup);
            app.FeatureExtractionTab.Title = 'Feature Extraction';
            app.RIGHTLEGButton = uibutton(app.FeatureExtractionTab, 'push');
            app.RIGHTLEGButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTLEGButtonPushed, true);
            app.RIGHTLEGButton.Text = 'RIGHT LEG';
            app.SPECTRAButton = uibutton(app.FeatureExtractionTab, 'push');
            app.SPECTRAButton.ButtonPushedFcn = createCallbackFcn(app, @SPECTRAButtonPushed, true);
            app.SPECTRAButton.Text = 'SPECTRA';
            app.CutoffSliderLabel = uilabel(app.FeatureExtractionTab);
            app.CutoffSliderLabel.Text = 'Cutoff (%):';
            app.CutoffSlider = uislider(app.FeatureExtractionTab);
            app.CutoffSlider.Limits = [0 1];
            app.CutoffSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.CutoffSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffSliderValueChanged, true);
            app.CutoffSlider.Value = 0.5;
            app.FILTERSIGNALButton = uibutton(app.FeatureExtractionTab, 'push');
            app.FILTERSIGNALButton.ButtonPushedFcn = createCallbackFcn(app, @FILTERSIGNALButtonPushed, true);
            app.FILTERSIGNALButton.Text = 'FILTER SIGNAL';
            app.FILTERSPECTRAButton = uibutton(app.FeatureExtractionTab, 'push');
            app.FILTERSPECTRAButton.ButtonPushedFcn = createCallbackFcn(app, @FILTERSPECTRAButtonPushed, true);
            app.FILTERSPECTRAButton.Text = 'FILTER SPECTRA';
            app.GETCHARACTERISTICSButton = uibutton(app.FeatureExtractionTab, 'push');
            app.GETCHARACTERISTICSButton.ButtonPushedFcn = createCallbackFcn(app, @GETCHARACTERISTICSButtonPushed, true);
            app.GETCHARACTERISTICSButton.Text = 'GET CHARACTERISTICS';
            app.GaitcharacteristicsLabel = uilabel(app.FeatureExtractionTab);
            app.GaitcharacteristicsLabel.Text = 'Gait characteristics:';
            app.DistancemEditFieldLabel = uilabel(app.FeatureExtractionTab);
            app.DistancemEditFieldLabel.Text = 'Distance (m):';
            app.DistancemEditField = uieditfield(app.FeatureExtractionTab, 'numeric');
            app.DistancemEditField.Limits = [0 Inf];
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.FeatureExtractionTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.FeatureExtractionTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.FeatureExtractionTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.SAVEFEATURESTOPATHButton = uibutton(app.FeatureExtractionTab, 'push');
            app.SAVEFEATURESTOPATHButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEFEATURESTOPATHButtonPushed, true);
            app.SAVEFEATURESTOPATHButton.Text = 'SAVE  FEATURES TO PATH';
            app.UIAxes_4 = uiaxes(app.FeatureExtractionTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.LEFTLEGButton = uibutton(app.FeatureExtractionTab, 'push');
            app.LEFTLEGButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTLEGButtonPushed, true);
            app.LEFTLEGButton.Text = 'LEFT LEG';
            app.FeatureExtractionLabel = uilabel(app.FeatureExtractionTab);
            app.FeatureExtractionLabel.Text = 'Feature Extraction';
            app.UploadrightlegrecordLabel = uilabel(app.FeatureExtractionTab);
            app.UploadrightlegrecordLabel.Text = 'Upload right leg record';
            app.UploadleftlegrecordLabel = uilabel(app.FeatureExtractionTab);
            app.UploadleftlegrecordLabel.Text = 'Upload left leg record';
            app.PatientsnameEditFieldLabel = uilabel(app.FeatureExtractionTab);
            app.PatientsnameEditFieldLabel.Text = 'Patient''s name';
            app.PatientsnameEditField = uieditfield(app.FeatureExtractionTab, 'text');
            app.PatientsnameEditField.ValueChangedFcn = createCallbackFcn(app, @PatientsnameEditFieldValueChanged, true);
            app.PresstosavefeaturesLabel = uilabel(app.FeatureExtractionTab);
            app.PresstosavefeaturesLabel.Text = 'Press to save features';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.UIAxes5 = uiaxes(app.ClassificationTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.ClassificationTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.ClassifyButton = uibutton(app.ClassificationTab, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.NumberofwindowsEditFieldLabel = uilabel(app.ClassificationTab);
            app.NumberofwindowsEditFieldLabel.Text = 'Number of windows';
            app.NumberofwindowsEditField = uieditfield(app.ClassificationTab, 'numeric');
            app.NumberofwindowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsEditFieldValueChanged, true);
            app.NumberofwindowsEditField.Value = 12;
            app.CreatedatamatrixButton = uibutton(app.ClassificationTab, 'push');
            app.CreatedatamatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreatedatamatrixButtonPushed, true);
            app.CreatedatamatrixButton.Text = 'Create data matrix';
            app.FrequencyrangeButtonGroup = uibuttongroup(app.ClassificationTab);
            app.FrequencyrangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyrangeButtonGroupSelectionChanged, true);
            app.FrequencyrangeButtonGroup.Title = 'Frequency range:';
            app.HzButton = uiradiobutton(app.FrequencyrangeButtonGroup);
            app.HzButton.Text = '6.25 - 12.5 Hz';
            app.HzButton.Value = true;
            app.HzButton_2 = uiradiobutton(app.FrequencyrangeButtonGroup);
            app.HzButton_2.Text = '12.5 - 25 Hz';
            app.HzButton_3 = uiradiobutton(app.FrequencyrangeButtonGroup);
            app.HzButton_3.Text = '25 - 50 Hz';
            app.UIAxes7 = uiaxes(app.ClassificationTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.ClassificationLabel = uilabel(app.ClassificationTab);
            app.ClassificationLabel.Text = 'Classification';
            app.NumberofneighboursButtonGroup = uibuttongroup(app.ClassificationTab);
            app.NumberofneighboursButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumberofneighboursButtonGroupSelectionChanged, true);
            app.NumberofneighboursButtonGroup.Title = 'Number of neighbours:';
            app.Button_3 = uiradiobutton(app.NumberofneighboursButtonGroup);
            app.Button_3.Text = '3';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.NumberofneighboursButtonGroup);
            app.Button_4.Text = '5';
            app.Button_5 = uiradiobutton(app.NumberofneighboursButtonGroup);
            app.Button_5.Text = '7';
            app.LoaddatamatrixButton = uibutton(app.ClassificationTab, 'push');
            app.LoaddatamatrixButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatamatrixButtonPushed, true);
            app.LoaddatamatrixButton.Text = 'Load data matrix';
            app.LoaddatamatrixorcreateanewoneLabel = uilabel(app.ClassificationTab);
            app.LoaddatamatrixorcreateanewoneLabel.Text = 'Load data matrix or create a new one';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MotionAnalysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionAnalysisUIFigure)
        end
    end
end